classdef dynaconn_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        uipanel1          matlab.ui.container.Panel
        axes1             matlab.ui.control.UIAxes
        axes2             matlab.ui.control.UIAxes
        text12            matlab.ui.control.Label
        popupmenu1        matlab.ui.control.DropDown
        popupmenu2        matlab.ui.control.DropDown
        winSizeText       matlab.ui.control.Label
        stepSizeText      matlab.ui.control.Label
        exitButton        matlab.ui.control.Button
        winUpButton       matlab.ui.control.Button
        winDnButton       matlab.ui.control.Button
        stepUpButton      matlab.ui.control.Button
        stepDnButton      matlab.ui.control.Button
        winSizeLabel      matlab.ui.control.Label
        stepSizeLabel     matlab.ui.control.Label
        subjText          matlab.ui.control.Label
        evResult          matlab.ui.control.Label
        corrResult        matlab.ui.control.Label
        subjSelPopupmenu  matlab.ui.control.DropDown
        actionPopupmenu   matlab.ui.control.DropDown
        text24            matlab.ui.control.Label
        selectionText     matlab.ui.control.Label
        popupmenu3        matlab.ui.control.DropDown
        popupmenu4        matlab.ui.control.DropDown
        groupText         matlab.ui.control.Label
        text27            matlab.ui.control.Label
    end

    
    methods (Access = private)
        function compsHandle = callCompDisplayWindow(app, handles)
                % 1st check is subject has already been selected
                if  handles.FormData.status.setup == 0
                    errordlg('Setup must be run first');
                    return;
                end
            
                % Save data to be retrieved by "Show Comps" GUI
                setappdata(0,'mode',handles.mode);
                setappdata(0,'regMap',handles.FormData.regMap);
                setappdata(0,'objN1',handles.FormData.objN1);
                setappdata(0,'objN2',handles.FormData.objN2);
                setappdata(0,'mask_ind',handles.FormData.mask_ind);
                setappdata(0,'subjProp',handles.FormData.subjProp);
                setappdata(0,'subjNum',handles.FormData.subjNum);
                % Show Components
                compsHandle = DFC_CompDisplay();
        end
        
        function initialize_gui(app, hObject, handles, isreset)
            % If the FormData field is present and the reset flag is false, it means
            % we are we are just re-initializing a GUI by calling it from the cmd line
            % while it is up. So, bail out as we dont want to reset the data.
                if isfield(handles, 'FormData') && ~isreset
                    return;
                end
                % Start in network mode instead of region ('reg') mode if mode hasn't
                % been defined yet (ie. staring up the GUI). If it's already defined
                % then we must be resetting
                if ~isfield(handles, 'mode')
                    handles.mode = 'net';
                end
            
                % Add required paths
                %addpath([pwd filesep 'spm8']);
            
                % Initialize the form data
                handles.FormData.subjNum = 1;
                handles.FormData.status.setup = 0; % 1 when setup has been run
                handles.FormData.status.group = 0; % 1 when there is group info loaded.
                handles.FormData.objN1 = 1;
                handles.FormData.objN2 = 1;
                handles.FormData.groupN1 = 1;
                handles.FormData.groupN2 = 1;
                handles.FormData.windowSize = 32;
                handles.FormData.stepSize = 8;
                handles.FormData.cmap = 1;
               % handles.FormData.FullFileName = 'none';
                handles.FormData.PCRADataExist = 0;  % Check for data after set-up
            
                % Add up and down arrow images to up/down buttons
                ulf = imread('U.png');
                set(handles.winUpButton,'Cdata',ulf);
                set(handles.stepUpButton,'Cdata',ulf);
                dlf = imread('D.png');
                set(handles.winDnButton,'Cdata',dlf);
                set(handles.stepDnButton,'Cdata',dlf);
            
                % Init the GUI strings
                toggleGUIelements(app, handles, 'off'); % Until setup is run all popup menus are hidden
            
                if strcmpi(handles.mode,'net')
                    set(handles.uipanel1,'Title','GIFT Mode','FontSize',16, ...
                    'FontName', 'Helvetica', 'FontUnits', 'pixels');
                    set(handles.corrResult,'String','Avg comp. corr between TCs = ');
                    set(handles.text12,'String','Total corr between comp. corr from plot');
                    set(handles.selectionText,'String','Component selection');
                    title(handles.axes1, 'TC of the Components (Normalized)', ...
                    'FontSize',14, 'FontName', 'Helvetica', 'FontUnits', 'pixels');
                    title(handles.axes2, 'Component Correlation (Normalized)', ...
                    'FontSize',14, 'FontName', 'Helvetica', 'FontUnits', 'pixels');
                elseif strcmpi(handles.mode,'reg')
                    set(handles.uipanel1,'Title','Region Mode','FontSize',16, ...
                    'FontName', 'Helvetica', 'FontUnits', 'pixels');
                    set(handles.corrResult,'String','Avg comp. corr between regions = ');
                    set(handles.text12,'String','Total corr between region corr from plot');
                    set(handles.selectionText,'String','Region selection');
                    title(handles.axes1, 'Average of Regions (Normalized)', ...
                    'FontSize',14, 'FontName', 'Helvetica', 'FontUnits', 'pixels');
                    title(handles.axes2, 'Region Correlation (Normalized)', ...
                    'FontSize',14, 'FontName', 'Helvetica', 'FontUnits', 'pixels');
                elseif strcmpi(handles.mode,'group')
                    set(handles.uipanel1,'Title','Group Mode','FontSize',16, ...
                    'FontName', 'Helvetica', 'FontUnits', 'pixels');
                    set(handles.corrResult,'String','Avg comp. corr with EV = ');
                    set(handles.text12,'String','Total corr between EV corr from plot');
                    set(handles.selectionText,'String','Component selection');
                    title(handles.axes1, 'Group DFC Avg', ...
                    'FontSize',14, 'FontName', 'Helvetica', 'FontUnits', 'pixels');
                    title(handles.axes2, 'p-values', ...
                    'FontSize',14, 'FontName', 'Helvetica', 'FontUnits', 'pixels');
                end
            
                % If on windows, use parallel computing, on mac the OS does parallel itself
                if strcmpi(computer('arch'),'win64')
                    handles.FormData.usePfor = 1;
                else
                    handles.FormData.usePfor = 0;
                end
            
                % Add a menu bar so the user can use the zoom in tool
                %set(handles.figure1, 'Toolbar', 'figure', 'menubar', 'figure');
                %drawnow;
            
                % Add a help menu that brings up the users guide, but don't re-add if
                % we are just reseting
                if ~isreset
                    mh = uimenu(handles.figure1,'Label','Help');
                    frh = uimenu(mh,'Label','User Manual ...',...
                                                    'Callback', @app.openUserGuide);
                end
            
                % Save any data placed in handles
                guidata(hObject,handles);
        end
        
        function matrixButton_Callback(app, hObject, eventdata, handles)
                % Shorten some form data var names to simplify code
                status = handles.FormData.status;
                subjProp = handles.FormData.subjProp;
                PCRADataExist = handles.FormData.PCRADataExist;
            
                % 1st check is subject has already been selected
                if  status.setup == 0
                    errordlg('Setup must be run first');
                    return;
                end
                % 2nd check is subject has already been selected
                if  status.group == 0
                    errordlg('Group setup must be run first');
                    return;
                end
            
                if ~PCRADataExist && strcmpi(handles.mode,'reg')
                    errordlg('All region mean data must be precompiled first');
                    return;
                end
            
                % Call function to find or generate DFC data
                [AveFileName,h] = dfc_findDFCData(handles);
                waitfor(AveFileName);
                if h ~= 0, close(h); end;
            
                % Save data to be retrieved network plot window
                setappdata(0,'dataFile',AveFileName);
                setappdata(0,'mode',handles.mode);
                setappdata(0,'cmap',handles.FormData.cmap);
                % Bring up network plot window
                h = DFC_NetworkPlot;
                waitfor(h);
            
                % Retrieve data sent from setup GUI
                objN1 = getappdata(0,'objN1');
                objN2 = getappdata(0,'objN2');
                subjNum = getappdata(0,'subjNum');
                handles.FormData.cmap = getappdata(0,'cmap');
                if objN1 ~= 0
                    % Update form data
                    handles.FormData.objN1 = objN1;
                    handles.FormData.objN2 = objN2;
                    if strcmpi(handles.mode,'group')
                        for i = 1 : length(subjProp)
                            groupNums(i) = subjProp(i).group;
                        end
                        uniqueGroups = unique(groupNums);
                        nGroup = length(uniqueGroups);
                        groupList = combnk(1:nGroup,2);
                        groupN1 = groupList(subjNum,1);
                        groupN2 = groupList(subjNum,2);
                        % Set the groups based of the new subjNum
                        set(handles.popupmenu3, 'value', groupN1);
                        set(handles.popupmenu4, 'value', groupN2);
                    else
                        handles.FormData.subjNum = subjNum;
                    end
            
                    % Set the popup component selectors based on data returned from corrMap
                    set(handles.popupmenu1, 'value', objN1);
                    set(handles.popupmenu2, 'value', objN2);
                    set(handles.subjSelPopupmenu,'value', subjNum);
            
                    % Update label and plot based on retrieved data
                    if strcmpi(handles.mode,'net')
                        dfc_plotNet(handles);
                    elseif strcmpi(handles.mode,'reg')
                        dfc_plotReg(handles);
                    elseif strcmpi(handles.mode,'group')
                        dfc_plotGroup(handles);
                    end
            
                    % Update the component display window if its open
                    if isfield(handles,'compsHandle')
                        if ishandle(handles.compsHandle);
                            % Get the position of the current component window
                            oldPosition = get(handles.compsHandle,'Position');
                            close(handles.compsHandle); % close the window
                            % Open a new component window and save the handle
                            h = callCompDisplayWindow(app, handles);
                            handles.compsHandle = h;
                            guidata(hObject,handles); % Save the handle data
                            % Move the new comp window to the old location
                            set(handles.compsHandle,'Position',oldPosition);
                        end
                    end
            
                end
                guidata(hObject,handles);
        end
        
        function modeButton_Callback(app, modeStep, hObject, eventdata, handles)
                % Clear form data
                handles.FormData = [];
            
                % If in network mode
                if strcmpi(handles.mode,'net')
                    if modeStep == 1
                        handles.mode = 'reg';
                    else
                        handles.mode = 'group';
                    end
                elseif strcmpi(handles.mode,'reg')
                    if modeStep == 1
                        handles.mode = 'net';
                    else
                        handles.mode = 'group';
                    end
                elseif strcmpi(handles.mode,'group')
                    if modeStep == 1
                        handles.mode = 'net';
                    else
                        handles.mode = 'reg';
                    end
                end
                % Clear current axes before changing modes
                cla(handles.axes1,'reset');
                cla(handles.axes2,'reset');
                % Start GUI again
                isreset = 1;
                initialize_gui(app, hObject, handles, isreset)
        end
        
        function openUserGuide(app, hObject, eventdata)
                open('DynaConnUserGuide.pdf')
        end
        
        function outputDFCData(app, handles)
                % Get file name to write to
                [file,path] = uiputfile('*.mat','Save mat file');
                if file ~= 0
                    % Open file and write out subject full file names
                    saveFile = [path file];
                    outData = dfc_outputDFCData(handles);
                    save(saveFile, 'outData');
                end
        end
        
        function outputRegCover(app, handles)
                % Get file name to write to
                [file,path] = uiputfile('*.txt','Save txt file');
                if file ~= 0
                    % Open file and write out subject full file names
                    saveFile = [path file];
                    dfc_outputRegCover(saveFile, handles);
                end
        end
        
        function replotDFCGraphs(app, hObject, handles)
                % Plot TC, and correlation data
                if strcmpi(handles.mode,'net')
                    dfc_plotNet(handles);
                elseif strcmpi(handles.mode,'reg')
                    % If mean was selected make sure it was build
                    if ~handles.FormData.PCRADataExist
                        errordlg('All region mean data must be precompiled first');
                        return;
                    end
                    dfc_plotReg(handles);
                elseif strcmpi(handles.mode,'group')
                    dfc_plotGroup(handles);
                end
                % Update the component display window if its open
                if isfield(handles,'compsHandle')
                    if ishandle(handles.compsHandle);
                        % Get the position of the current component window
                        oldPosition = get(handles.compsHandle,'Position');
                        close(handles.compsHandle); % close the window
                        % Open a new component window and save the handle
                        h = callCompDisplayWindow(app, handles);
                        handles.compsHandle = h;
                        guidata(hObject,handles); % Save the handle data
                        % Move the new comp window to the old location
                        set(handles.compsHandle,'Position',oldPosition);
                    end
                end
        end
        
        function setupButton_Callback(app, hObject, eventdata, handles)
                % Set initial status of setup to not complete
                if ~isfield(handles.FormData.status, 'setup')
                    handles.FormData.status.setup = false;
                end
                % Tell the setup GUI what mode to work in
                setappdata(0,'mode',handles.mode);
                % Call and wait for setup GUI
                h = DFC_Setup;
                waitfor(h);
            
                % Check if setup was run to completion
                setupStatus = getappdata(0,'setupStatus');
            
                % If the setup was completed then retrieve data sent from setup GUI
                if setupStatus
                    % Retrieve data sent from setup GUI
                    handles.FormData.mask_ind =         getappdata(0,'mask_ind');
                    handles.FormData.subjDir =          getappdata(0,'subjDir');
                    handles.FormData.subjProp =         getappdata(0,'subjProp');
                    handles.FormData.regMap =           getappdata(0,'regMap');
                    handles.FormData.evTR =             getappdata(0,'evTR');
                    handles.FormData.evScanUnitType =   getappdata(0,'evScanUnitType');
                    handles.FormData.workDir =          getappdata(0,'workDir');
                    handles.FormData.status =           getappdata(0,'status');
                    handles.FormData.status.setup = setupStatus;
                    % Enable GUI buttons now
                    toggleGUIelements(app, handles, 'on');
            
                    % Shorten some of the vars for use in this function
                    subjNum = handles.FormData.subjNum;
                    subjProp = handles.FormData.subjProp;
                    regMap = handles.FormData.regMap;
            
                    if strcmpi(handles.mode,'net')
                        % Update list_items with component numbers
                        for i = 1 : handles.FormData.subjProp(subjNum).icDim(4)
                            list_items{i} = ['Component ' num2str(i)];
                        end
                    elseif strcmpi(handles.mode,'reg')
                    % Update list_items with regions
                        for i = 1 : length(regMap)
                            reg_items{i} = regMap(i).name{1};
                        end
                        list_items = cellArray2charArray(reg_items);
                    elseif strcmpi(handles.mode,'group')
                    % Update list_items with groups
                        for i = 1 : length(subjProp)
                            groupNums(i) = handles.FormData.subjProp(i).group;
                        end
                        % Build list of groups
                        uniqueGN = unique(groupNums);
                        for i = 1 : length(uniqueGN)
                            group_items{i} = ['Group ' num2str(i)];
                        end
                        % Build list of components
                        for i = 1 : handles.FormData.subjProp(subjNum).icDim(4)
                            list_items{i} = ['Component ' num2str(i)];
                        end
                        set(handles.popupmenu3,'String',group_items,'Value',1);
                        set(handles.popupmenu4,'String',group_items,'Value',1);
                    end
            
                    % Update popup menus
                    set(handles.popupmenu1,'String',list_items,'Value',1);
                    set(handles.popupmenu2,'String',list_items,'Value',1);
                    codeList = popPopMenu(subjProp, 'code');
                    handles.FormData.subjNum = 1;
                    set(handles.subjSelPopupmenu,'String', codeList,'Value',handles.FormData.subjNum);
            
                    % If in region mode find or build precompiled region data
                    if strcmpi(handles.mode,'reg')
                        handles = dfc_findPreCompRegData(handles); % If found sets PCRADataExist = 1
                    end
                end
            
                guidata(hObject,handles);
        end
        
        function showComponents(app, hObject, eventdata, handles)
                h = callCompDisplayWindow(app, handles);
                handles.compsHandle = h;
                guidata(hObject,handles);
        end
        
        function toggleGUIelements(app, handles, state)
            % Turn on or off all the GUI buttons and popups not  associated with
            % choosing the next action (ie setup)
                set(handles.popupmenu1,     'Visible', state);
                set(handles.popupmenu2,     'Visible', state);
                set(handles.winUpButton,    'Visible', state);
                set(handles.winDnButton,    'Visible', state);
                set(handles.stepUpButton,   'Visible', state);
                set(handles.stepDnButton,   'Visible', state);
                set(handles.winSizeText,    'Visible', state);
                set(handles.winSizeLabel,   'Visible', state);
                set(handles.stepSizeText,   'Visible', state);
                set(handles.stepSizeLabel,  'Visible', state);
                set(handles.selectionText,  'Visible', state);
                set(handles.corrResult,     'Visible', state);
                set(handles.text12,         'Visible', state);
                set(handles.evResult,       'Visible', state);
                set(handles.axes1,          'Visible', state);
                set(handles.axes2,          'Visible', state);
                set(handles.subjText,       'Visible', state);
                set(handles.subjSelPopupmenu,'Visible', state);
                % Set value of group select popups if in group mode
                if strcmpi(handles.mode,'group')
                    set(handles.groupText,  'Visible', state);
                    set(handles.popupmenu3, 'Visible', state);
                    set(handles.popupmenu4, 'Visible', state);
                    set(handles.subjText,   'Visible','off');
                    set(handles.subjSelPopupmenu,'Visible','off');
                else
                    set(handles.groupText,  'Visible', 'off');
                    set(handles.popupmenu3, 'Visible', 'off');
                    set(handles.popupmenu4, 'Visible', 'off');
                end
                % Set value of action/function popup menu
                ix = 1;   % Menu index
                actionString{ix} = 'Select function';
                ix = ix + 1;
                actionString{ix} = 'Configure setup';
                ix = ix + 1;
                if strcmpi(handles.mode, 'net')
                    actionString{ix} = 'Change to Region mode';
                    ix = ix + 1;
                    actionString{ix} = 'Change to Group mode';
                    ix = ix + 1;
                elseif strcmpi(handles.mode, 'reg')
                    actionString{ix} = 'Change to GIFT mode';
                    ix = ix + 1;
                    actionString{ix} = 'Change to Group mode';
                    ix = ix + 1;
                elseif strcmpi(handles.mode, 'group')
                    actionString{ix} = 'Change to GIFT mode';
                    ix = ix + 1;
                    actionString{ix} = 'Change to Region mode';
                    ix = ix + 1;
                end
                if strcmpi(state, 'on')
                    actionString{ix} = 'Show Regions';
                    ix = ix + 1;
                    if  handles.FormData.status.group == 1
                        actionString{ix} = 'Component Region Matrix';
                        ix = ix + 1;
            %             %Andrew added 5/20/21
             %             actionString(ix) = 'Output DFC data';
            %             ix = ix + 1;
                    end
                    if strcmpi(handles.mode, 'net')
                        actionString{ix} = 'Output DFC data';
                        ix = ix + 1;
                        actionString{ix} = 'Output EV modulated data';
                        ix = ix + 1;
                        actionString{ix} = 'Output Static FC data';
                        ix = ix + 1;
                        actionString{ix} = 'Output all correlation window';
                        ix = ix + 1;
                        actionString{ix} = 'Output region coverage';
                    end
                end
                set(handles.actionPopupmenu,'String',actionString,'Value',1);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function dynaconn_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to dynaconn (see VARARGIN)
            
            % Choose default command line output for dynaconn
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % Initialize GUIDATA
            %if
            %handles.mode = varargin{1};
            initialize_gui(app, hObject, handles, false);
        end

        % Value changed function: actionPopupmenu
        function actionPopupmenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                action = get(handles.actionPopupmenu,'Value');
                if action == 1
                elseif action == 2
                    setupButton_Callback(app, hObject, eventdata, handles);
                elseif action == 3
                    modeButton_Callback(app, 1, hObject, eventdata, handles);
                elseif action == 4
                    modeButton_Callback(app, 2, hObject, eventdata, handles);
                elseif action == 5
                    showComponents(app, hObject, eventdata, handles);
                %Andrew added 5/20/21
                elseif action == 6
                    dfc_buildRegDFC(regMap,subjProp,settings);
                end
            
                % Since menu will be different if group info has not been loaded
                if handles.FormData.status.group == 1
                    if action == 6
                        matrixButton_Callback(app, hObject, eventdata, handles);
                    elseif action == 7
                        handles.FormData.dataType = 1;
                        outputDFCData(app, handles);
                    elseif action == 8
                        handles.FormData.dataType = 2;
                        outputDFCData(app, handles);
                    elseif action == 9
                        handles.FormData.dataType = 3;
                        outputDFCData(app, handles);
                    elseif action == 10
                        handles.FormData.dataType = 4;
                        outputDFCData(app, handles);
                    elseif action == 11
                        handles.defaults.zscorecut = 1.7;
                        outputRegCover(app, handles);
                    end
                else
                    if action == 6
                        handles.FormData.dataType = 1;
                        outputDFCData(app, handles);
                    elseif action == 7
                        handles.FormData.dataType = 2;
                        outputDFCData(app, handles);
                    elseif action == 8
                        handles.FormData.dataType = 3;
                        outputDFCData(app, handles);
                    elseif action == 9
                        handles.FormData.dataType = 4;
                        outputDFCData(app, handles);
                    elseif action == 10
                        outputRegCover(app, handles);
                    end
            
                end
                % Return action selection back to default position
                set(handles.actionPopupmenu,'Value',1);
        end

        % Button pushed function: exitButton
        function exitButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                close all;
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % 1st check is subject has already been selected
                if  handles.FormData.status.setup == 0
                    errordlg('Setup must be run first');
                    return;
                end
                % Save the new component 1 selection
                handles.FormData.objN1 = get(hObject,'Value');
                guidata(hObject,handles);
                replotDFCGraphs(app, hObject, handles);
        end

        % Value changed function: popupmenu2
        function popupmenu2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % 1st check is subject has already been selected
                if  handles.FormData.status.setup == 0
                    errordlg('Setup must be run first');
                    return;
                end
                % Save the new component 2 selection
                handles.FormData.objN2 = get(hObject,'Value');
                guidata(hObject,handles);
                replotDFCGraphs(app, hObject,handles);
        end

        % Value changed function: popupmenu3
        function popupmenu3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % 1st check is subject has already been selected
                if  handles.FormData.status.setup == 0
                    errordlg('Setup must be run first');
                    return;
                end
                % Save the new group 1 selection
                handles.FormData.groupN1 = get(hObject,'Value');
                guidata(hObject,handles);
                replotDFCGraphs(app, handles);
        end

        % Value changed function: popupmenu4
        function popupmenu4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % 1st check is subject has already been selected
                if  handles.FormData.status.setup == 0
                    errordlg('Setup must be run first');
                    return;
                end
                % Save the new group 2 selection
                handles.FormData.groupN2 = get(hObject,'Value');
                guidata(hObject,handles);
                replotDFCGraphs(app, hObject, handles);
        end

        % Button pushed function: stepDnButton
        function stepDnButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % 1st check is subject has already been selected
                if  handles.FormData.status.setup == 0
                    errordlg('Setup must be run first');
                    return;
                end
                handles.FormData.stepSize = handles.FormData.stepSize -1;
                guidata(hObject,handles);
                set(handles.stepSizeLabel,'String',handles.FormData.stepSize,'Value',1);
                % Plot TC, and correlation data
                if strcmpi(handles.mode,'net')
                    dfc_plotNet(handles);
                elseif strcmpi(handles.mode,'reg')
                    if ~handles.FormData.PCRADataExist
                        errordlg('All region mean data must be precompiled first');
                        return;
                    end
                    dfc_plotReg(handles);
                elseif strcmpi(handles.mode,'group')
                    dfc_plotGroup(handles);
                end
        end

        % Button pushed function: stepUpButton
        function stepUpButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % 1st check is subject has already been selected
                if  handles.FormData.status.setup == 0
                    errordlg('Setup must be run first');
                    return;
                end
                handles.FormData.stepSize = handles.FormData.stepSize + 1;
                guidata(hObject,handles);
                set(handles.stepSizeLabel,'String',handles.FormData.stepSize,'Value',1);
                % Plot TC, and correlation data
                if strcmpi(handles.mode,'net')
                    dfc_plotNet(handles);
                elseif strcmpi(handles.mode,'reg')
                    if ~handles.FormData.PCRADataExist
                        errordlg('All region mean data must be precompiled first');
                        return;
                    end
                    dfc_plotReg(handles);
                elseif strcmpi(handles.mode,'group')
                    dfc_plotGroup(handles);
                end
        end

        % Value changed function: subjSelPopupmenu
        function subjSelPopupmenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % Get the code selection number from the GUI
                handles.FormData.subjNum = get(hObject,'Value');
                guidata(hObject,handles);
                replotDFCGraphs(app, hObject, handles);
        end

        % Button pushed function: winDnButton
        function winDnButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % 1st check is subject has already been selected
                if  handles.FormData.status.setup == 0
                    errordlg('Setup must be run first');
                    return;
                end
                handles.FormData.windowSize = handles.FormData.windowSize - 1;
                guidata(hObject,handles);
                set(handles.winSizeLabel,'String',handles.FormData.windowSize,'Value',1);
                % Plot regions, and correlation data
                if strcmpi(handles.mode,'net')
                    dfc_plotNet(handles);
                elseif strcmpi(handles.mode,'reg')
                    if ~handles.FormData.PCRADataExist
                        errordlg('All region mean data must be precompiled first');
                        return;
                    end
                    dfc_plotReg(handles);
                elseif strcmpi(handles.mode,'group')
                    dfc_plotGroup(handles);
                end
        end

        % Button pushed function: winUpButton
        function winUpButton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                % 1st check is subject has already been selected
                if  handles.FormData.status.setup == 0
                    errordlg('Setup must be run first');
                    return;
                end
                handles.FormData.windowSize = handles.FormData.windowSize + 1;
                guidata(hObject,handles);
                set(handles.winSizeLabel,'String',handles.FormData.windowSize,'Value',1);
                % Plot regions, and correlation data
                if strcmpi(handles.mode,'net')
                    dfc_plotNet(handles);
                elseif strcmpi(handles.mode,'reg')
                    if ~handles.FormData.PCRADataExist
                        errordlg('All region mean data must be precompiled first');
                        return;
                    end
                    dfc_plotReg(handles);
                elseif strcmpi(handles.mode,'group')
                    dfc_plotGroup(handles);
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'dynaconn';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'DFC - Region Mode';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.uipanel1);
            app.axes2.Tag = 'axes2';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Total corr between region. corr from plot';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'Choose 1st Component'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'Choose 1st Component';
            app.popupmenu2 = uidropdown(app.uipanel1);
            app.popupmenu2.Items = {'Choose 2nd Component'};
            app.popupmenu2.ValueChangedFcn = createCallbackFcn(app, @popupmenu2_Callback, true);
            app.popupmenu2.Tag = 'popupmenu2';
            app.popupmenu2.Value = 'Choose 2nd Component';
            app.winSizeText = uilabel(app.uipanel1);
            app.winSizeText.Tag = 'winSizeText';
            app.winSizeText.Text = 'Window Size:';
            app.stepSizeText = uilabel(app.uipanel1);
            app.stepSizeText.Tag = 'stepSizeText';
            app.stepSizeText.Text = 'Step Size:';
            app.exitButton = uibutton(app.uipanel1, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButton_Callback, true);
            app.exitButton.Tag = 'exitButton';
            app.exitButton.Text = 'Exit';
            app.winUpButton = uibutton(app.uipanel1, 'push');
            app.winUpButton.ButtonPushedFcn = createCallbackFcn(app, @winUpButton_Callback, true);
            app.winUpButton.Tag = 'winUpButton';
            app.winUpButton.Text = '';
            app.winDnButton = uibutton(app.uipanel1, 'push');
            app.winDnButton.ButtonPushedFcn = createCallbackFcn(app, @winDnButton_Callback, true);
            app.winDnButton.Tag = 'winDnButton';
            app.winDnButton.Text = '';
            app.stepUpButton = uibutton(app.uipanel1, 'push');
            app.stepUpButton.ButtonPushedFcn = createCallbackFcn(app, @stepUpButton_Callback, true);
            app.stepUpButton.Tag = 'stepUpButton';
            app.stepUpButton.Text = '';
            app.stepDnButton = uibutton(app.uipanel1, 'push');
            app.stepDnButton.ButtonPushedFcn = createCallbackFcn(app, @stepDnButton_Callback, true);
            app.stepDnButton.Tag = 'stepDnButton';
            app.stepDnButton.Text = '';
            app.winSizeLabel = uilabel(app.uipanel1);
            app.winSizeLabel.Tag = 'winSizeLabel';
            app.winSizeLabel.Text = '32';
            app.stepSizeLabel = uilabel(app.uipanel1);
            app.stepSizeLabel.Tag = 'stepSizeLabel';
            app.stepSizeLabel.Text = '8';
            app.subjText = uilabel(app.uipanel1);
            app.subjText.Tag = 'subjText';
            app.subjText.Text = 'Subject Loaded:';
            app.evResult = uilabel(app.uipanel1);
            app.evResult.Tag = 'evResult';
            app.evResult.Text = 'above and expected value from EV file = ';
            app.corrResult = uilabel(app.uipanel1);
            app.corrResult.Tag = 'corrResult';
            app.corrResult.Text = 'Avg corr between regions =';
            app.subjSelPopupmenu = uidropdown(app.uipanel1);
            app.subjSelPopupmenu.Items = {'Select Subject'};
            app.subjSelPopupmenu.ValueChangedFcn = createCallbackFcn(app, @subjSelPopupmenu_Callback, true);
            app.subjSelPopupmenu.Tag = 'subjSelPopupmenu';
            app.subjSelPopupmenu.Value = 'Select Subject';
            app.actionPopupmenu = uidropdown(app.uipanel1);
            app.actionPopupmenu.Items = {'Select Action', 'Setup', 'Change Mode', 'Show Regions', 'Component Region Matrix', ''};
            app.actionPopupmenu.ValueChangedFcn = createCallbackFcn(app, @actionPopupmenu_Callback, true);
            app.actionPopupmenu.Tag = 'actionPopupmenu';
            app.actionPopupmenu.Value = 'Select Action';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Action Menu';
            app.selectionText = uilabel(app.uipanel1);
            app.selectionText.Tag = 'selectionText';
            app.selectionText.Text = 'Selection Text';
            app.popupmenu3 = uidropdown(app.uipanel1);
            app.popupmenu3.Items = {'Choose 1st Group'};
            app.popupmenu3.ValueChangedFcn = createCallbackFcn(app, @popupmenu3_Callback, true);
            app.popupmenu3.Tag = 'popupmenu3';
            app.popupmenu3.Value = 'Choose 1st Group';
            app.popupmenu4 = uidropdown(app.uipanel1);
            app.popupmenu4.Items = {'Choose 2nd Group'};
            app.popupmenu4.ValueChangedFcn = createCallbackFcn(app, @popupmenu4_Callback, true);
            app.popupmenu4.Tag = 'popupmenu4';
            app.popupmenu4.Value = 'Choose 2nd Group';
            app.groupText = uilabel(app.uipanel1);
            app.groupText.Tag = 'groupText';
            app.groupText.Text = 'Choose Group Number';
            app.text27 = uilabel(app.figure1);
            app.text27.Tag = 'text27';
            app.text27.Text = 'DynaConn';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dynaconn_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)dynaconn_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end